% description: Restaurants
% (C) Pascal Mossay, Grega Smrkolj, and Jong K. Shin,
% Newcastle University, 2019
% ------------------------------------------------------------------------
% We do not take any responsibility and we are not liable for any damage
% caused through use of this application.
% ------------------------------------------------------------------------
% This application supplements our paper:  P. Mossay, G.Smrkolj,
% and J. K. Shin (2019),  "Quality Differentiation and Spatial Clustering
% among Restaurants". It allows the user to recreate Figure 1 in our paper
% for any city in the sample and for an arbitrary value of the ranking
% percentile to devide the restaurants into Top and Bottom groups.
% The user can select one of the 96 cities in England & Wales for which
% the locations of top and bottom restaurants are then displayed on a city
% map, together with their geographic centroids. A circle with a radius
% equal to the average distance of restaurants in a group from the group's
% geographic centroid can also be displayed.
% The list of restaurants and their rankings were obtained from
% (C)TripAdvisor's website between January 25 and February 14, 2018.
% City boundaries in the form of geometric polygons with GPS
% coordinates were obtained from (C)OpenStreetMap and MapIt. Data
% from OpenStreetMap are available under the Open Database Licence,
% http://www.openstreetmap.org/ copyright. MapIt contains Ordnance Survey
% data (C)Crown copyright and database rights 2010-17, which is licensed
% under the Open Government Licence v3.0.
% The restaurants' mailing addresses, obtained from TripAdvisor,
% were converted to GPS coordinates via APIs provided by
% Google Maps(TM) and Bing Maps(TM).
classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RestaurantsUIFigure          matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        DisplayButton                matlab.ui.control.StateButton
        DataSelectionPanel           matlab.ui.container.Panel
        ShowaveragedistanceCheckBox  matlab.ui.control.CheckBox
        BottomRestaurantsoCheckBox   matlab.ui.control.CheckBox
        TopRestaurantsCheckBox       matlab.ui.control.CheckBox
        PercentileEditField          matlab.ui.control.NumericEditField
        PercentileEditFieldLabel     matlab.ui.control.Label
        CityDropDown                 matlab.ui.control.DropDown
        CityDropDownLabel            matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DisplayButton
        function DisplayButtonValueChanged(app, event)
   
      FileName = 'RestaurantsData.csv'; %full path and file name of the data
      DAT = readtable(FileName);

%-------------------SELECTION PARAMETERS-----------------------------------
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
SelectedCity = strrep(app.CityDropDown.Value, ' ', '');
SelectedCity = strrep(SelectedCity, '-', '');

%Select percentile (0-99); i.e. 10 - bottom 10% vs top 10%, 50 - median) 
percentile = app.PercentileEditField.Value;  
top        = app.TopRestaurantsCheckBox.Value;  
bottom     = app.BottomRestaurantsoCheckBox.Value; 

%MAPSHOW plot 
dshow2 = app.ShowaveragedistanceCheckBox.Value;
%remove outliers beyond the city bounds
out = 1;  
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%--------------------------------------------------------------------------
% Load a map of the chosen city
load('citycode.mat')
for i = 1:length(city)
   cloc = contains(city{i,2},SelectedCity,'IgnoreCase',true);
   if cloc==1
       cind = i;
       break
   end
end

load('BoundsCell.mat')
pLat = BoundsCell{cind,1}(:,1);
pLon = BoundsCell{cind,1}(:,2);
%--------------------------------------------------------------------------
%Select correct sample of restaurants
In = DAT(contains(DAT.City, SelectedCity,'IgnoreCase',true),:); % select data from the SelectedCity
RestInCityWithRank = ~isnan(In.RankingInTheCity); % select restaurants with ranking and inside the city
T = In(RestInCityWithRank,:);
T = sortrows(T,'RankingInTheCity','ascend');  
% 
T=T((~isnan(T.lat) & ~isnan(T.lng)),:); % temporarily
                                        % currently some GPS coordinates are NAN 
                                        
%remove those observations that are outside the city border
if out==1
in = inpolygon(T.lat, T.lng, pLat, pLon);
T = T(in,:);
end

%remove those with GeoCodeReRun=1 (NaN for latitude or outside UK)
T2=T((T.GeoCodeReRun==0),:);                                      

%new raking by Tripadvisor popularity index
for i = 1:height(T2)
    T2.NewRanking(i,1)=i;  % create new ranking
end

%--------------creating percentile groups----------------------------------
p = prctile(T2.NewRanking,[percentile 100-percentile]);
     
  if percentile ~=100
    T2.TopX(T2.NewRanking < p(1),1) = 1;
    T2.BottomX(T2.NewRanking >= p(2),1) = 1;
    T3=T2((T2.TopX==1 | T2.BottomX==1),:);
  else
    T2.TopX(:,1) = 1;
    T2.BottomX(:,1) = 0;
    T3=T2;
  end
                     
%--------------------------------------------------------------------------                                          
%Geographic Mean (assuming data is distributed on a sphere)---                           
if top==1
    vlatT = T3.lat(T3.TopX==1);
    vlonT = T3.lng(T3.TopX==1);
    [mlatT,mlonT ] = meanm(vlatT, vlonT);
end
if bottom==1
    vlatB = T3.lat(T3.BottomX==1);
    vlonB = T3.lng(T3.BottomX==1);
    [mlatB,mlonB ] = meanm(vlatB, vlonB);
end

%Standard Distance--------------------------

%expand the mean point for 'distance'
if top==1
biglatT = mlatT(ones([size(vlatT,1),1]),:);
biglonT = mlonT(ones([size(vlonT,1),1]),:);
end
if bottom==1
biglatB = mlatB(ones([size(vlatB,1),1]),:);
biglonB = mlonB(ones([size(vlonB,1),1]),:);
end

% Calculate the great circle distance from each point to the centroid
if top==1
    rngT = distance('gc',biglatT,biglonT,vlatT,vlonT);
end
if bottom==1
    rngB = distance('gc',biglatB,biglonB,vlatB,vlonB);
end

%calculate the standard/average distance deviation
if top==1
  distT = stdist(vlatT, vlonT);
end
if bottom==1
  distB = stdist(vlatB, vlonB);
end   

%transform degrees into kilometres
if top==1
  distTkm = deg2km(distT); 
end

if bottom==1
  distBkm = deg2km(distB);
end
    
%--------------------------------------------------------------------------
%data projection
dczone = utmzone(mean(pLat,'omitnan'),mean(pLon,'omitnan'));
utmstruct = defaultm('utm'); 
utmstruct.zone = dczone;  
utmstruct.geoid = wgs84Ellipsoid;
utmstruct = defaultm(utmstruct);
[pLat,pLon] = mfwdtran(utmstruct,pLat,pLon);
[T3.lat,T3.lng] = mfwdtran(utmstruct,T3.lat,T3.lng);
if top==1
[lat1t,lon1t] = scircle1(mlatT,mlonT,distT);
[lat1t, lon1t] = mfwdtran(utmstruct,lat1t, lon1t);
[mlatT, mlonT] = mfwdtran(utmstruct, mlatT, mlonT);
end
if bottom==1
[lat1b,lon1b] = scircle1(mlatB,mlonB,distB);
[lat1b, lon1b] = mfwdtran(utmstruct,lat1b, lon1b);
[mlatB, mlonB] = mfwdtran(utmstruct, mlatB, mlonB);
end

  plot(app.UIAxes, pLat,pLon, 'LineWidth',1.5,'Color','k')
  hold(app.UIAxes,'on')
  
   if top==1 && bottom==1
    for j = 1:height(T3)
       if T3.BottomX(j,1)==1; Col='b'; Mark='o';  
       plot(app.UIAxes, T3.lat(j,1),T3.lng(j,1), 'Marker', Mark,'MarkerEdgeColor',Col)
       end
    end

   for j = 1:height(T3)
       if T3.TopX(j,1)==1; Col='r'; Mark='+'; 
       plot(app.UIAxes,T3.lat(j,1),T3.lng(j,1),'Marker', Mark,'MarkerEdgeColor',Col)
       end
   end
    
   elseif top==1 && bottom==0
     for j = 1:height(T3)
       if T3.TopX(j,1)==1; Col='r'; Mark='+'; end
       if T3.BottomX(j,1)==1; continue; end  
       plot(app.UIAxes, T3.lat(j,1),T3.lng(j,1),'Marker', Mark,'MarkerEdgeColor',Col)   
     end  
   elseif top==0 && bottom==1
       for j = 1:height(T3)
       if T3.BottomX(j,1)==1; Col='b'; Mark='o'; end 
       if T3.TopX(j,1)==1; continue; end
       plot(app.UIAxes, T3.lat(j,1),T3.lng(j,1), 'Marker', Mark,'MarkerEdgeColor',Col)
       end
   end
    %mean values
    if top==1
    plot(app.UIAxes, mlatT, mlonT, 'Marker', 's',...
            'MarkerFaceColor', 'r', 'MarkerEdgeColor','k','Markersize',10)
       if dshow2==1
        plot(app.UIAxes, lat1t,lon1t,'Color','r', 'LineWidth',1);
       end
    end
    if bottom==1
    plot(app.UIAxes, mlatB, mlonB, 'Marker', 'o',...
            'MarkerFaceColor', 'b', 'MarkerEdgeColor','b','Markersize',10)
        if dshow2==1
        plot(app.UIAxes, lat1b,lon1b,'LineStyle','--','LineWidth',1);
       end
    end  
    hold(app.UIAxes,'off')
    axis(app.UIAxes, 'tight');
        app.DisplayButton.Value = 0;  
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RestaurantsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {374, 374};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RestaurantsUIFigure = uifigure('Visible', 'off');
            app.RestaurantsUIFigure.Name = 'Restaurants';
            app.RestaurantsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RestaurantsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataSelectionPanel = uipanel(app.LeftPanel);
            app.DataSelectionPanel.Title = 'Data Selection';
            app.CityDropDownLabel = uilabel(app.DataSelectionPanel);
            app.CityDropDownLabel.Text = 'City';
            app.CityDropDown = uidropdown(app.DataSelectionPanel);
            app.CityDropDown.Items = {'Basildon', ' Basingstoke', ' Bath', ' Bedford', ' Birkenhead', ' Birmingham', ' Blackburn', ' Blackpool', ' Bolton', ' Bournemouth', ' Bradford', ' Brighton', ' Bristol', ' Burnley', ' Cambridge', ' Canterbury', ' Cardiff', ' Carlisle', ' Chelmsford', ' Cheltenham', ' Chester', ' Chesterfield', ' Chichester', ' Colchester', ' Coventry', ' Crawley', ' Croydon', ' Darlington', ' Derby', ' Doncaster', ' Dudley', ' Durham', ' Eastbourne', ' Enfield', ' Exeter', ' Gloucester', ' Hereford', ' Lancaster', ' Leamington Spa', ' Leeds', ' Leicester', ' Lichfield', ' Lincoln', ' Liverpool', ' Luton', ' Maidstone', ' Manchester', ' Mansfield', ' Middlesbrough', ' Milton Keynes', ' Newcastle upon Tyne', ' Newport', ' Northampton', ' Norwich', ' Nottingham', ' Nuneaton', ' Oldham', ' Oxford', ' Peterborough', ' Plymouth', ' Poole', ' Portsmouth', ' Preston', ' Reading', ' Rochdale', ' Rotherham', ' Salford', ' Salisbury', ' Sheffield', ' Solihull', ' Southampton', ' Southend on Sea', ' Southport', ' St Albans', ' Stevenage', ' St Helens', ' Stockport', ' Stoke-on-Trent', ' Sunderland', ' Sutton', ' Sutton Coldfield', ' Swansea', ' Swindon', ' Telford', ' Wakefield', ' Warrington', ' Watford', ' Wembley', ' West Bromwich', ' Wigan', ' Winchester', ' Woking', ' Wolverhampton', ' Worcester', ' Worthing', ' York'};
            app.CityDropDown.Value = ' Newcastle upon Tyne';
            app.PercentileEditFieldLabel = uilabel(app.DataSelectionPanel);
            app.PercentileEditFieldLabel.Text = 'Percentile';
            app.PercentileEditField = uieditfield(app.DataSelectionPanel, 'numeric');
            app.PercentileEditField.Limits = [0 100];
            app.PercentileEditField.Value = 25;
            app.TopRestaurantsCheckBox = uicheckbox(app.DataSelectionPanel);
            app.TopRestaurantsCheckBox.Text = '  Top Restaurants (+)';
            app.TopRestaurantsCheckBox.Value = true;
            app.BottomRestaurantsoCheckBox = uicheckbox(app.DataSelectionPanel);
            app.BottomRestaurantsoCheckBox.Text = '  Bottom Restaurants (o)';
            app.BottomRestaurantsoCheckBox.Value = true;
            app.ShowaveragedistanceCheckBox = uicheckbox(app.DataSelectionPanel);
            app.ShowaveragedistanceCheckBox.Text = '  Show average distance';
            app.ShowaveragedistanceCheckBox.Value = true;
            app.DisplayButton = uibutton(app.LeftPanel, 'state');
            app.DisplayButton.ValueChangedFcn = createCallbackFcn(app, @DisplayButtonValueChanged, true);
            app.DisplayButton.Text = 'Display';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.RestaurantsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RestaurantsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RestaurantsUIFigure)
        end
    end
end